classdef CellDensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HistogramPanel                matlab.ui.container.Panel
        BandwidthEditField            matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel       matlab.ui.control.Label
        NumBinsEditField              matlab.ui.control.NumericEditField
        NumBinsEditFieldLabel         matlab.ui.control.Label
        SubmitButton                  matlab.ui.control.Button
        VariableDropDown_2            matlab.ui.control.DropDown
        VariableDropDown_2Label       matlab.ui.control.Label
        GraphOptionsPanel             matlab.ui.container.Panel
        DensityGradientCheckBox       matlab.ui.control.CheckBox
        TitleSizeSpinner              matlab.ui.control.Spinner
        TitleSizeSpinnerLabel         matlab.ui.control.Label
        AxesLabelSizeSpinner          matlab.ui.control.Spinner
        AxesLabelSizeSpinnerLabel     matlab.ui.control.Label
        AxisMarkerSizeSpinner         matlab.ui.control.Spinner
        AxisMarkerSizeSpinnerLabel    matlab.ui.control.Label
        FillPointsCheckBox            matlab.ui.control.CheckBox
        PointTransparencySlider       matlab.ui.control.Slider
        PointTransparencySliderLabel  matlab.ui.control.Label
        PointColorDropDown            matlab.ui.control.DropDown
        PointColorDropDownLabel       matlab.ui.control.Label
        PointSizeSlider               matlab.ui.control.Slider
        PointSizeSliderLabel          matlab.ui.control.Label
        YAxisTypeDropDown             matlab.ui.control.DropDown
        YAxisTypeDropDownLabel        matlab.ui.control.Label
        XAxisTypeDropDown             matlab.ui.control.DropDown
        XAxisTypeDropDownLabel        matlab.ui.control.Label
        ClearAllButton                matlab.ui.control.Button
        GatingSelectionsPanel         matlab.ui.container.Panel
        ClearButton                   matlab.ui.control.Button
        AddParameterButton            matlab.ui.control.Button
        SubmitGroupingButton          matlab.ui.control.Button
        CurrentParamsTextArea         matlab.ui.control.TextArea
        CurrentParamsTextAreaLabel    matlab.ui.control.Label
        SubmitGatingButton            matlab.ui.control.Button
        OptionsDropDown               matlab.ui.control.DropDown
        OptionsDropDownLabel          matlab.ui.control.Label
        VariableDropDown              matlab.ui.control.DropDown
        VariableDropDownLabel         matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        PlotTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        HistogramTab                  matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        AxisSelectionsPanel           matlab.ui.container.Panel
        YAxisDropDown                 matlab.ui.control.DropDown
        YAxisDropDownLabel            matlab.ui.control.Label
        XAxisDropDown                 matlab.ui.control.DropDown
        XAxisDropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % table that holds all quantitative data
        metaData % table that holds all non-numerical data
        x_data % contains the column selected for x-axis
        y_data % contains the column selected for y-axis
        row_index % property that keeps track of which row index will be plotted
        temp_row_index % temperary index held to keep track of parameters before gating/grouping submissin
        metaCols % column names of the metadata
        holding % checks if plot is holding
        currentPlots % contains the labels of all displayed plots for legend purposes
        newPlot % logical that dictates whether you are plotting a new plot or just updating an existing one.
        
        vars
        annotations
        
        newSubmit % boolean that tracks whether or not a new gating/grouping was submitted. only updates dscatter working memories if this is true. 
        dscatter_x % working memories used for dscatter
        dscatter_y
                
    end
    
    methods (Access = private)
        
        % controls all plot refreshes
        function refreshPlot(app)
            
            % updates to most recent row_index
            if app.newPlot
                app.row_index = app.temp_row_index;
            end            
            
            % ensures columns to be plotted are up-to-date with dropdown selections 
            app.x_data = app.Data.(app.XAxisDropDown.Value);
            app.y_data = app.Data.(app.YAxisDropDown.Value);
            
            % update working memory for dscatter
            %%%%% THIS WORKS BUT DOES TOO MUCH. WANT TO ONLY ADD TO WORKING
            %%%%% MEMORY IF GATING OR GROUPING IS PRESSED ??
            %%%%% BASICALLY YOU NEED BETTER CONTROL OF WHAT TO BE ADDED TO
            %%%%% DENSITY WORKING MEMORY
%             app.dscatter_x = cat(1,app.dscatter_x,app.x_data);
%             app.dscatter_y = cat(1,app.dscatter_y,app.y_data);
%             app.dscatter_x = [app.dscatter_x,app.x_data];
%             app.dscatter_y = [app.dscatter_y,app.y_data];
            
            % title and axes labels
            app.UIAxes.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
            app.UIAxes.XLabel.String = app.XAxisDropDown.Value;
            app.UIAxes.YLabel.String = app.YAxisDropDown.Value;
            
            % font size adjustment for title, axes, and markers
            app.UIAxes.FontSize = app.AxisMarkerSizeSpinner.Value;                       
            app.UIAxes.LabelFontSizeMultiplier = app.AxesLabelSizeSpinner.Value/app.AxisMarkerSizeSpinner.Value;
            app.UIAxes.TitleFontSizeMultiplier = app.TitleSizeSpinner.Value/app.AxisMarkerSizeSpinner.Value;
            
            
            % avoids replotting existing plot when hold is on
            if ~app.newPlot
                items = get(app.UIAxes,'Children');
                delete(items(1))
            end
            
%             if app.newSubmit
%                 app.dscatter_x = cat(1,app.dscatter_x,app.x_data(app.row_index));
%                 app.dscatter_y = cat(1,app.dscatter_y,app.y_data(app.row_index));
%             end
%             
%             disp(app.dscatter_x)


            %%%%%%%%%%%% SCATTER %%%%%%%%%%%%%%%
            
            if ~app.DensityGradientCheckBox.Value

                if app.FillPointsCheckBox.Value
                    plot = scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index),app.PointSizeSlider.Value,app.PointColorDropDown.Value,'filled');
                else
                    plot = scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index),app.PointSizeSlider.Value,app.PointColorDropDown.Value);
                end
               
                % point transparency
                plot.MarkerFaceAlpha = app.PointTransparencySlider.Value;
                
                % set axis scale type          
                if app.XAxisTypeDropDown.Value == "Log"
                    set(app.UIAxes,'xscale','log')
                else 
                    set(app.UIAxes,'xscale','linear')
                end
                
                if app.YAxisTypeDropDown.Value == "Log"
                    set(app.UIAxes,'yscale','log')
                else 
                    set(app.UIAxes,'yscale','linear')
                end
                 
                % legend
                if ~isempty(app.currentPlots)
                    set(plot,{'DisplayName'},app.currentPlots)
                    legend(app.UIAxes,'show')
                else
                    set(plot,{'DisplayName'},{'DATA1'})
                    legend(app.UIAxes,'show')
                end        
    
                % holds plots when grouping
                if app.holding
                    hold(app.UIAxes,'on');
                    app.CurrentParamsTextArea.Value = ""; % clear gating display
                    
                    app.temp_row_index = true([height(app.Data),1]); % reset index %%% IS THIS NEEDED ANYMORE?
                    
                    % reset vars/annotations for grouping
                    app.vars = [];
                    app.annotations = [];
                    
                end
            
            else
                      
            %%%%%%%%%%%%%% DSCATTER W/OUT WORKING MEMORY %%%%%%%%%%%%%%%%%%%          
                if app.XAxisTypeDropDown.Value == "Log" & app.YAxisTypeDropDown.Value == "Log"
                    plot = dscatter(app.x_data(app.row_index),app.y_data(app.row_index),'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'logx',true,'logy',true,'app',app);
                
                elseif app.XAxisTypeDropDown.Value == "Log" & app.YAxisTypeDropDown.Value ~= "Log"
                    
                    plot = dscatter(app.x_data(app.row_index),app.y_data(app.row_index),'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'logx',true,'app',app);
                    
                elseif app.XAxisTypeDropDown.Value ~= "Log" & app.YAxisTypeDropDown.Value == "Log"
                    
                    plot = dscatter(app.x_data(app.row_index),app.y_data(app.row_index),'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'logy',true,'app',app);
                    
                else
                    
                    plot = dscatter(app.x_data(app.row_index),app.y_data(app.row_index),'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'app',app);
                    
                end

            %%%%%%%%%%%% DSCATTER W/ WORKING MEMORY %%%%%%%%%%%%%%%%%%%%%%
                
                %disp(app.dscatter_x)
                %disp(app.x_data(app.row_index))
                
%                 if app.XAxisTypeDropDown.Value == "Log" & app.YAxisTypeDropDown.Value == "Log"
%                     plot = dscatter(app.dscatter_x,app.dscatter_y,'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'logx',true,'logy',true,'app',app);
%                 
%                 elseif app.XAxisTypeDropDown.Value == "Log" & app.YAxisTypeDropDown.Value ~= "Log"
%                     
%                     plot = dscatter(app.dscatter_x,app.dscatter_y,'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'logx',true,'app',app);
%                     
%                 elseif app.XAxisTypeDropDown.Value ~= "Log" & app.YAxisTypeDropDown.Value == "Log"
%                     
%                     plot = dscatter(app.dscatter_x,app.dscatter_y,'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'logy',true,'app',app);
%                     
%                 else
%                     
%                     plot = dscatter(app.dscatter_x,app.dscatter_y,'smoothing',10,'msize',app.PointSizeSlider.Value,'filled',app.FillPointsCheckBox.Value,'app',app);
%                     
%                 end
                                                 
            end
            
%             app.newSubmit = false;
                                      
        end
        
        function histogramPlot(app)
            
            % for computing kernel density estimation
            pts_pmt = linspace(prctile(log10(app.Data.(app.VariableDropDown_2.Value)),0.1)-2,prctile(log10(app.Data.(app.VariableDropDown_2.Value)),99.9),2000);
            
            % THE MINUS 2 ADJUST YOUR LINE PLOT TO INCLUDE LOWER END DATA.
            % Maybe can add that to be user input??
            
            % den is the calculated density. x is the data on your x axis
            %[den,x] = ksdensity(log10(app.Data.(app.VariableDropDown_2.Value)(app.Data.(app.VariableDropDown_2.Value)>0)),pts_pmt,'Bandwidth',0.15); %adjust bandwith based on user input
            [den,x] = ksdensity(log10(app.Data.(app.VariableDropDown_2.Value)(app.Data.(app.VariableDropDown_2.Value)>0)),pts_pmt,'Bandwidth',app.BandwidthEditField.Value); % adjusts smoothness of density curve. higher numbers are smoother

            bins = app.NumBinsEditField.Value; % adjusts number of histogram bins

            obj2plot = app.Data.(app.VariableDropDown_2.Value)(app.Data.(app.VariableDropDown_2.Value)>0);            
            [~,edges] = histcounts(log10(obj2plot),bins);
            hh = histogram(app.UIAxes3,obj2plot,10.^edges);
            ylabel(app.UIAxes3,'Counts');
            set(app.UIAxes3,'xscale','log');
            hold(app.UIAxes3,'on');
            yyaxis(app.UIAxes3,'right');
           
            plot(app.UIAxes3,10.^x,den,'LineWidth',2);         
            
            set(app.UIAxes3,'xscale','log'); % is this redundant?           
            xlim(app.UIAxes3,[10^-3,10^4]); 
            ylabel(app.UIAxes3,'Kernel density estimation');
            xlabel(app.UIAxes3,app.VariableDropDown_2.Value);
            
            hYLabel = get(app.UIAxes3,'YLabel');

            % title
            app.UIAxes3.Title.String = app.VariableDropDown_2.Value + " Histogram and Probability Density";
            
            hold(app.UIAxes3,'off')
                   

        end
        
        function [cell_id_fbm,ind_fbm_out,ind_meta_out] = meta_grab_cell_id(app,fbm_in,metadata_in,var,annotation)
            % Desciption: meta_grab_cell_id function serves to help user quickly grab unique cell idenfiers
            %     as well as corresponding FBM table row indecies from user-defined qualifiers on the metadata
            %     sheet by specifing the metadata variable name(s) and annotation(s) to grab.
            % Required inputs:
            %     fbm_in          : input FBM table which the user wish to grab matching unique cell ids and row indecies
            %     metadata_in     : input metadata table which the user wish to set qualifiers on
            %     var             : a single or array of strings indicating metadata variable names to qualify
            %     annontation     : *same dimension(s) as var*  a single or array of strings indicating specific annotation to grab from each metadata variable
            
            % only works if you have rownames
            
            ind_meta_interest = true;
            
            % gets all of the relevant indexes
            for i = 1:length(var)
                ind_meta_interest = ind_meta_interest & metadata_in.(var(i))==annotation(i);
            end
            
            % gathers the cell ids based on above indexes
            cell_id_out = metadata_in.Row(ind_meta_interest);
            
            [cell_id_fbm,ind_fbm_out]=intersect(fbm_in.Row,cell_id_out,'stable');
            
            [~,ind_meta_out] = intersect(metadata_in.Row,cell_id_fbm,'stable');
            
            if length(cell_id_fbm)~=length(cell_id_out)
                warning('Warning: input FBM matrix does not contain all the cells that satisfy input metadata requirements')
                warning_count = [num2str(length(cell_id_out)-length(cell_id_fbm)),' out of ',num2str(length(cell_id_out)),' cells are dropped'];
                warning(warning_count)
            end
        end

        function hAxes = dscatter(X,Y, varargin)
            % DSCATTER creates a scatter plot coloured by density.
            %
            %   DSCATTER(X,Y) creates a scatterplot of X and Y at the locations
            %   specified by the vectors X and Y (which must be the same size), colored
            %   by the density of the points.
            %
            %   DSCATTER(...,'MARKER',M) allows you to set the marker for the
            %   scatter plot. Default is 's', square.
            %
            %   DSCATTER(...,'MSIZE',MS) allows you to set the marker size for the
            %   scatter plot. Default is 10.
            %
            %   DSCATTER(...,'FILLED',false) sets the markers in the scatter plot to be
            %   outline. The default is to use filled markers.
            %
            %   DSCATTER(...,'PLOTTYPE',TYPE) allows you to create other ways of
            %   plotting the scatter data. Options are "surf','mesh' and 'contour'.
            %   These create surf, mesh and contour plots colored by density of the
            %   scatter data.
            %
            %   DSCATTER(...,'BINS',[NX,NY]) allows you to set the number of bins used
            %   for the 2D histogram used to estimate the density. The default is to
            %   use the number of unique values in X and Y up to a maximum of 200.
            %
            %   DSCATTER(...,'SMOOTHING',LAMBDA) allows you to set the smoothing factor
            %   used by the density estimator. The default value is 20 which roughly
            %   means that the smoothing is over 20 bins around a given point.
            %
            %   DSCATTER(...,'LOGY',true) uses a log scale for the yaxis.
            %
            %   Examples:
            %
            %       [data, params] = fcsread('SampleFACS');
            %       dscatter(data(:,1),10.^(data(:,2)/256),'log',1)
            %       % Add contours
            %       hold on
            %       dscatter(data(:,1),10.^(data(:,2)/256),'log',1,'plottype','contour')
            %       hold off
            %       xlabel(params(1).LongName); ylabel(params(2).LongName);
            %       
            %   See also FCSREAD, SCATTER.
            
            % Copyright 2003-2004 The MathWorks, Inc.
            % $Revision:  $   $Date:  $
            
            % Reference:
            % Paul H. C. Eilers and Jelle J. Goeman
            % Enhancing scatterplots with smoothed densities
            % Bioinformatics, Mar 2004; 20: 623 - 628.
            
            Y(X<0) = [];
            X(X<0) = [];
            X(Y<0) = [];
            Y(Y<0) = [];
            
            lambda = [];
            nbins = [];
            plottype = 'scatter';
            contourFlag = false;
            msize = 5;
            marker = 's';
            logy = false;
            logx = false;
            filled = true;
            if nargin > 2
                if rem(nargin,2) == 1
                    error('Bioinfo:IncorrectNumberOfArguments',...
                        'Incorrect number of arguments to %s.',mfilename);
                end
                okargs = {'smoothing','bins','plottype','logy','logx','marker','msize','filled','app'};
                for j=1:2:nargin-2
                    pname = varargin{j};
                    pval = varargin{j+1};
                    k = strmatch(lower(pname), okargs); %#ok
                    if isempty(k)
                        error('Bioinfo:UnknownParameterName',...
                            'Unknown parameter name: %s.',pname);
                    elseif length(k)>1
                        error('Bioinfo:AmbiguousParameterName',...
                            'Ambiguous parameter name: %s.',pname);
                    else
                        switch(k)
                            case 1  % smoothing factor
                                if isnumeric(pval)
                                    lambda = pval;
                                else
                                    error('Bioinfo:InvalidScoringMatrix','Invalid smoothing parameter.');
                                end
                            case 2
                                if isscalar(pval)
                                    nbins = [ pval pval];
                                else
                                    nbins = pval;
                                end
                            case 3
                                plottype = pval;
                            case 4
                                logy = pval;
                                Y = log10(Y);
                            case 5
                                logx = pval;
                                X = log10(X);
%                             case 6
%                                 contourFlag = pval;
                            case 6
                                marker = pval;
                            case 7
                                msize = pval;
                            case 8
                                filled = pval;
                            case 9
                                app = pval;
                        end
                    end
                end
            end
            
            minx = min(X,[],1);
            maxx = max(X,[],1);
            miny = min(Y,[],1);
            maxy = max(Y,[],1);
            
            if isempty(nbins)
                nbins = [min(numel(unique(X)),200) ,min(numel(unique(Y)),200) ];
            end
            
            if isempty(lambda)
                lambda = 20;
            end
            
            edges1 = linspace(minx, maxx, nbins(1)+1);
            ctrs1 = edges1(1:end-1) + .5*diff(edges1);
            edges1 = [-Inf edges1(2:end-1) Inf];
            edges2 = linspace(miny, maxy, nbins(2)+1);
            ctrs2 = edges2(1:end-1) + .5*diff(edges2);
            edges2 = [-Inf edges2(2:end-1) Inf];
            Y;
            edges1;
            [n,p] = size(X);
            bin = zeros(n,2);
            % Reverse the columns to put the first column of X along the horizontal
            % axis, the second along the vertical.
            [dum,bin(:,2)] = histc(X,edges1);
            [dum,bin(:,1)] = histc(Y,edges2);
            H = accumarray(bin,1,nbins([2 1])) ./ n;
            G = smooth1D(H,nbins(2)/lambda);
            F = smooth1D(G',nbins(1)/lambda)';
            % = filter2D(H,lambda);
            
            if logy
                ctrs2 = 10.^ctrs2;
                Y = 10.^Y;
            end
            
            if logx
                ctrs1 = 10.^ctrs1;
                X = 10.^X;
            end
            okTypes = {'surf','mesh','contour','image','scatter'};
            k = strmatch(lower(plottype), okTypes); %#ok
            if isempty(k)
                error('dscatter:UnknownPlotType',...
                    'Unknown plot type: %s.',plottype);
            elseif length(k)>1
                error('dscatter:AmbiguousPlotType',...
                    'Ambiguous plot type: %s.',plottype);
            else
                switch(k)
            
                    case 1 %'surf'
                        h = surf(app.UIAxes,ctrs1,ctrs2,F,'edgealpha',0);
                    case 2 % 'mesh'
                        h = mesh(ctrs1,ctrs2,F);
                    case 3 %'contour'
                        [dummy, h] =contour(app.UIAxes,ctrs1,ctrs2,F);
                    case 4 %'image'
                        nc = 256;
                        F = F./max(F(:));
                        colormap(app.UIAxes,repmat(linspace(1,0,nc)',1,3));
                        h =image(app.UIAxes,ctrs1,ctrs2,floor(nc.*F) + 1);
                    case 5 %'scatter'
                        F = F./max(F(:));
                        ind = sub2ind(size(F),bin(:,1),bin(:,2));
                        col = F(ind);
                        if filled
                            h = scatter(app.UIAxes,X,Y,msize,col,marker,'filled');
                        else
                            h = scatter(app.UIAxes,X,Y,msize,col,marker);
                        end
                end
                
                % maintains legend
                if ~isempty(app.currentPlots)
                    set(h,{'DisplayName'},app.currentPlots)
                    legend(app.UIAxes,'show')
                else
                    set(h,{'DisplayName'},{'DATA1'})
                    legend(app.UIAxes,'show')
                end     
                
                % transparency
                h.MarkerFaceAlpha = app.PointTransparencySlider.Value;
                
                
            end
            
            if logy
%                 set(gca,'yscale','log');
                set(app.UIAxes,'yscale','log');
            else 
                set(app.UIAxes,'yscale','linear');
            end
            if logx
%                 set(gca,'xscale','log');
                set(app.UIAxes,'xscale','log');
            else
                set(app.UIAxes,'xscale','linear');
            end
            if nargout > 0
                hAxes = get(h,'parent');
            end
            %%%% This method is quicker for symmetric data.
            % function Z = filter2D(Y,bw)
            % z = -1:(1/bw):1;
            % k = .75 * (1 - z.^2);
            % k = k ./ sum(k);
            % Z = filter2(k'*k,Y);
            
            function Z = smooth1D(Y,lambda)
            [m,n] = size(Y);
            E = eye(m);
            D1 = diff(E,1);
            D2 = diff(D1,1);
            P = lambda.^2 .* D2'*D2 + 2.*lambda .* D1'*D1;
            Z = (E + P) \ Y;

            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)
            
            app.holding = false;
            
            app.vars = [];
            app.annotations = [];
            
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            app.newPlot = false;
%             app.newSubmit = true;
            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            app.newPlot = false;
%             app.newSubmit = true;
            
            refreshPlot(app)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            %%%%%%%%%%% CSV File Input %%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [Density.file_fbm,Density.path_fbm] = uigetfile({'*.*','Select the fbm data file.'});
            fbm_base = readtable(strcat(Density.path_fbm,Density.file_fbm),'ReadRowNames',true,'ReadVariableNames',true,'Delimiter',' ');
            head(fbm_base)
            
            fbmColNames = fbm_base.Properties.VariableNames;
            
            app.Data = fbm_base;
            
            [nr,nc] = size(app.Data);
            for i = 1:nc
                app.XAxisDropDown.Items(i) = fbmColNames(i); 
                app.YAxisDropDown.Items(i) = fbmColNames(i); 
                
                % loads histogram options %%%% THIS NEEDS TO BE REDONE TOO
                if i>1
                    app.VariableDropDown_2.Items(i-1) = fbmColNames(i);
                end
                
            end   
            
            
            [Density.file_metadata,Density.path_metadata] = uigetfile({'*.*','Select the metadata file.'});
            metadata_base = readtable(strcat(Density.path_metadata,Density.file_metadata),'ReadRowNames',true,'ReadVariableNames',true,'Delimiter',' ');
            head(metadata_base)  
            
            metadataColNames = metadata_base.Properties.VariableNames;
            
            app.metaData = metadata_base;
            app.metaCols = metadataColNames;
            
            [nr,nc] = size(app.metaData);
            app.VariableDropDown.Items(1) = {'None'}; % initialize metadata options
            app.OptionsDropDown.Items(:,:) = [];
            app.OptionsDropDown.Items(1) = {'None'};
            for i = 2:nc
                app.VariableDropDown.Items(i) = app.metaCols(i); 
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


%%%%%%%%%%%%%%%% OLD EXCEL FILE INPUT TYPE %%%%%%%%%%%%%%%%%%%%%%%%%       
            
%             % Loads Data
%             [Density.file_fbm,Density.path_fbm] = uigetfile({'*.*','Select the fbm data file.'});
%             DensityRead = readtable(strcat(Density.path_fbm,Density.file_fbm));
%             colNames = DensityRead.Properties.VariableNames;
%                     
%             app.Data = DensityRead;                    
%                       
%            
%             % loads axis dropdown options based on column names from DensityRead
%             [nr,nc] = size(app.Data);
%             for i = 2:nc
%                 app.XAxisDropDown.Items(i-1) = colNames(i); 
%                 app.YAxisDropDown.Items(i-1) = colNames(i); 
%                 
%                 % loads histogram options
%                 if i>2
%                     app.VariableDropDown_2.Items(i-2) = colNames(i);
%                 end
%                 
%             end            
%                                 
%             % Load MetaData
%             [Density.file_metadata,Density.path_metadata] = uigetfile({'*.*','Select the metadata file.'});
%             DensityRead = readtable(strcat(Density.path_metadata,Density.file_metadata));
%             colNames = DensityRead.Properties.VariableNames;
%             
%             app.metaData = DensityRead;
%             app.metaCols = colNames;
% 
%             % loads metadata dropdown options based on DensityRead
%             [nr,nc] = size(app.metaData);
%             app.VariableDropDown.Items(1) = {'None'}; % initialize metadata options
%             app.OptionsDropDown.Items(:,:) = [];
%             app.OptionsDropDown.Items(1) = {'None'};
%             for i = 2:nc
%                 app.VariableDropDown.Items(i) = app.metaCols(i); 
%             end


            % initializes index properties
            app.row_index = true([height(app.Data),1]);
            app.temp_row_index = true([height(app.Data),1]);
   
            app.newPlot = true;
%             app.newSubmit = false;
           
            
            refreshPlot(app)             
            
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            value = app.VariableDropDown.Value;
            
            % clears out the list
            app.OptionsDropDown.Items(:,:) = [];
            
            % loads in all unique options for gating
            [a,b] = unique(app.metaData(:,value),'stable');
            for i = 1:height(a)
                app.OptionsDropDown.Items(i) = a{i,1};
            end
            
        end

        % Button pushed function: SubmitGatingButton
        function SubmitGatingButtonPushed(app, event)
            
            app.currentPlots = app.CurrentParamsTextArea.Value; % updates current plots
            app.newPlot = true;
%             app.newSubmit = true;
            
            refreshPlot(app)
            
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)

            app.row_index = true([height(app.Data),1]); % reset row index
            app.temp_row_index = true([height(app.Data),1]);
            
            app.vars = [];
            app.annotations = [];
            
            app.dscatter_x = [];
            app.dscatter_y = [];
            
            app.CurrentParamsTextArea.Value = ""; % clear gating display
            app.currentPlots = {'DATA1'}; % this empty case is hard coded in
            
            % clear groupings
            app.holding = false;
            hold(app.UIAxes,'off');
            
            app.newPlot = true;
            
            refreshPlot(app)
        end

        % Button pushed function: SubmitGroupingButton
        function SubmitGroupingButtonPushed(app, event)
            
            app.currentPlots = app.CurrentParamsTextArea.Value;
            app.holding = true; 
            app.newPlot = true;
%             app.newSubmit = true;
            
            refreshPlot(app)
            
        end

        % Button pushed function: AddParameterButton
        function AddParameterButtonPushed(app, event)
            
            
            % turns gating selecting into string
            gating = convertCharsToStrings(app.OptionsDropDown.Value);
            % col is the data column chosen for gating
            col = app.VariableDropDown.Value;
            
            %temp_index = app.metaData{:,col} == gating;
            
            
            %%%%%%%%%%% PAINFULLY SLOW WAY TO DO CELLID GRABBING %%%%%%%%%%%%%
            
%             cellID = app.metaData.cell_id(temp_index); % grab cell IDs from METADATA based on temp_index
%             
%             for i = 1:length(cellID)
%                 
%                 t_index(i) = find(ismember(app.Data.cell_id,cellID(i)));
%                 
%             end
%                       
%             app.temp_row_index = t_index & app.temp_row_index;
         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
%             disp(class({col})) % cell
%             % col itself is class char. 
%            
%              disp(class(gating))
%              disp(class(col))
% 

% NEED TO THROW AN ERROR WHEN MORE THAN ONE ANNOTATION IS GIVEN PER VAR

            if isempty(app.vars) && isempty(app.annotations)
                app.vars = [string(col)];
                app.annotations = [string(gating)];
            else
                
                app.vars(end+1) = string(col);
                app.annotations(end+1) = string(gating);
                
            end
            
            
            [cell_id_fbm,ind_fbm_out,ind_meta_out] = meta_grab_cell_id(app,app.Data,app.metaData,app.vars,app.annotations); % not working because app.Data is a table             
%             disp(ind_fbm_out)
            app.temp_row_index = ind_fbm_out; % this is just a list of indexes instead of a logical. 
  
%             for i = 1:size(app.Data)
%                 if 
%             end
%             app.temp_row_index = app.Data.Properties.RowNames ==
            
            % allows for proper indexing even when app.Data and app.metaData are of different sizes. assumes app.metaData > app.Data in terms of size
%             for i = 1:size(app.temp_row_index)
%                 temp = app.temp_row_index(i) == temp_index(i);
%                 app.temp_row_index(i) = temp;
%             end
            %%%% SOMETHING ISN'T RIGHT THO SINCE PLOT DOESNT DISAPPEAR WHEN
            %%%% DIFFERENT TREATMENTS ALL ADDED TO SAME GATING (EX. DMSO +
            %%%% GEM RESULTS IN A NON-EMPTY PLOT

            %%% old %%%
            %app.temp_row_index = temp_index & app.temp_row_index;
            
            % display current params
            temp = convertCharsToStrings(app.CurrentParamsTextArea.Value);
            if temp == ""
                app.CurrentParamsTextArea.Value = gating;
            else
                app.CurrentParamsTextArea.Value = temp + ", " + gating;
            end            
                        
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            app.CurrentParamsTextArea.Value = ""; % clear gating display
            app.temp_row_index = true([height(app.Data),1]);
            
        end

        % Value changed function: XAxisTypeDropDown
        function XAxisTypeDropDownValueChanged(app, event)
            
            app.newPlot = false;            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisTypeDropDown
        function YAxisTypeDropDownValueChanged(app, event)
            app.newPlot = false;            
            refreshPlot(app)
            
        end

        % Value changed function: PointSizeSlider
        function PointSizeSliderValueChanged(app, event)
            app.newPlot = false;
            refreshPlot(app)
            
        end

        % Value changed function: PointColorDropDown
        function PointColorDropDownValueChanged(app, event)
            app.newPlot = false;
            refreshPlot(app)
            
        end

        % Value changed function: FillPointsCheckBox
        function FillPointsCheckBoxValueChanged(app, event)
            app.newPlot = false;
            refreshPlot(app)
            
        end

        % Value changed function: PointTransparencySlider
        function PointTransparencySliderValueChanged(app, event)
            app.newPlot = false;            
            refreshPlot(app)
            
        end

        % Value changed function: AxisMarkerSizeSpinner
        function AxisMarkerSizeSpinnerValueChanged(app, event)
            app.newPlot = false;
            refreshPlot(app)
            
        end

        % Value changed function: TitleSizeSpinner
        function TitleSizeSpinnerValueChanged(app, event)
            app.newPlot = false;
            refreshPlot(app)
            
        end

        % Value changed function: AxesLabelSizeSpinner
        function AxesLabelSizeSpinnerValueChanged(app, event)
            app.newPlot = false;
            refreshPlot(app)
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            
            cla(app.UIAxes3,'reset'); % creates weird tick marks at the top of the plot. 
            
            histogramPlot(app)
            
            
        end

        % Value changed function: DensityGradientCheckBox
        function DensityGradientCheckBoxValueChanged(app, event)
            app.newPlot = false;
            
            refreshPlot(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxisSelectionsPanel = uipanel(app.UIFigure);
            app.AxisSelectionsPanel.Title = 'Axis Selections';
            app.XAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.XAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'item1';
            app.YAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.YAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'item1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxes3 = uiaxes(app.HistogramTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GatingSelectionsPanel = uipanel(app.UIFigure);
            app.GatingSelectionsPanel.Title = 'Gating Selections';
            app.VariableDropDownLabel = uilabel(app.GatingSelectionsPanel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.GatingSelectionsPanel);
            app.VariableDropDown.Items = {'item1'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'item1';
            app.OptionsDropDownLabel = uilabel(app.GatingSelectionsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.GatingSelectionsPanel);
            app.OptionsDropDown.Items = {'item1'};
            app.OptionsDropDown.Value = 'item1';
            app.SubmitGatingButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.SubmitGatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGatingButtonPushed, true);
            app.SubmitGatingButton.Text = 'Submit Gating';
            app.CurrentParamsTextAreaLabel = uilabel(app.GatingSelectionsPanel);
            app.CurrentParamsTextAreaLabel.Text = 'Current Params';
            app.CurrentParamsTextArea = uitextarea(app.GatingSelectionsPanel);
            app.SubmitGroupingButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.SubmitGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGroupingButtonPushed, true);
            app.SubmitGroupingButton.Text = 'Submit Grouping';
            app.AddParameterButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.AddParameterButton.ButtonPushedFcn = createCallbackFcn(app, @AddParameterButtonPushed, true);
            app.AddParameterButton.Text = 'Add Parameter';
            app.ClearButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.GraphOptionsPanel = uipanel(app.UIFigure);
            app.GraphOptionsPanel.Title = 'Graph Options';
            app.XAxisTypeDropDownLabel = uilabel(app.GraphOptionsPanel);
            app.XAxisTypeDropDownLabel.Text = 'X-Axis Type';
            app.XAxisTypeDropDown = uidropdown(app.GraphOptionsPanel);
            app.XAxisTypeDropDown.Items = {'Linear', 'Log'};
            app.XAxisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisTypeDropDownValueChanged, true);
            app.XAxisTypeDropDown.Value = 'Linear';
            app.YAxisTypeDropDownLabel = uilabel(app.GraphOptionsPanel);
            app.YAxisTypeDropDownLabel.Text = 'Y-Axis Type';
            app.YAxisTypeDropDown = uidropdown(app.GraphOptionsPanel);
            app.YAxisTypeDropDown.Items = {'Linear', 'Log'};
            app.YAxisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisTypeDropDownValueChanged, true);
            app.YAxisTypeDropDown.Value = 'Linear';
            app.PointSizeSliderLabel = uilabel(app.GraphOptionsPanel);
            app.PointSizeSliderLabel.Text = 'Point Size';
            app.PointSizeSlider = uislider(app.GraphOptionsPanel);
            app.PointSizeSlider.Limits = [1 100];
            app.PointSizeSlider.MajorTicks = [1 11 21 31 41 51 61 71 81 91 100];
            app.PointSizeSlider.MajorTickLabels = {'1', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.PointSizeSlider.ValueChangedFcn = createCallbackFcn(app, @PointSizeSliderValueChanged, true);
            app.PointSizeSlider.Value = 20;
            app.PointColorDropDownLabel = uilabel(app.GraphOptionsPanel);
            app.PointColorDropDownLabel.Text = 'Point Color';
            app.PointColorDropDown = uidropdown(app.GraphOptionsPanel);
            app.PointColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.PointColorDropDown.ValueChangedFcn = createCallbackFcn(app, @PointColorDropDownValueChanged, true);
            app.PointColorDropDown.Value = 'red';
            app.PointTransparencySliderLabel = uilabel(app.GraphOptionsPanel);
            app.PointTransparencySliderLabel.Text = 'Point Transparency';
            app.PointTransparencySlider = uislider(app.GraphOptionsPanel);
            app.PointTransparencySlider.Limits = [0 1];
            app.PointTransparencySlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.PointTransparencySlider.ValueChangedFcn = createCallbackFcn(app, @PointTransparencySliderValueChanged, true);
            app.PointTransparencySlider.Value = 1;
            app.FillPointsCheckBox = uicheckbox(app.GraphOptionsPanel);
            app.FillPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillPointsCheckBoxValueChanged, true);
            app.FillPointsCheckBox.Text = 'Fill Points';
            app.AxisMarkerSizeSpinnerLabel = uilabel(app.GraphOptionsPanel);
            app.AxisMarkerSizeSpinnerLabel.Text = 'Axis Marker Size';
            app.AxisMarkerSizeSpinner = uispinner(app.GraphOptionsPanel);
            app.AxisMarkerSizeSpinner.Limits = [1 100];
            app.AxisMarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AxisMarkerSizeSpinnerValueChanged, true);
            app.AxisMarkerSizeSpinner.Value = 10;
            app.AxesLabelSizeSpinnerLabel = uilabel(app.GraphOptionsPanel);
            app.AxesLabelSizeSpinnerLabel.Text = 'Axes Label Size';
            app.AxesLabelSizeSpinner = uispinner(app.GraphOptionsPanel);
            app.AxesLabelSizeSpinner.Limits = [1 100];
            app.AxesLabelSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AxesLabelSizeSpinnerValueChanged, true);
            app.AxesLabelSizeSpinner.Value = 15;
            app.TitleSizeSpinnerLabel = uilabel(app.GraphOptionsPanel);
            app.TitleSizeSpinnerLabel.Text = 'Title Size';
            app.TitleSizeSpinner = uispinner(app.GraphOptionsPanel);
            app.TitleSizeSpinner.Limits = [1 100];
            app.TitleSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @TitleSizeSpinnerValueChanged, true);
            app.TitleSizeSpinner.Value = 20;
            app.DensityGradientCheckBox = uicheckbox(app.GraphOptionsPanel);
            app.DensityGradientCheckBox.ValueChangedFcn = createCallbackFcn(app, @DensityGradientCheckBoxValueChanged, true);
            app.DensityGradientCheckBox.Text = 'Density Gradient';
            app.HistogramPanel = uipanel(app.UIFigure);
            app.HistogramPanel.Title = 'Histogram';
            app.VariableDropDown_2Label = uilabel(app.HistogramPanel);
            app.VariableDropDown_2Label.Text = 'Variable';
            app.VariableDropDown_2 = uidropdown(app.HistogramPanel);
            app.VariableDropDown_2.Items = {'Option 1', 'Option 2'};
            app.SubmitButton = uibutton(app.HistogramPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.NumBinsEditFieldLabel = uilabel(app.HistogramPanel);
            app.NumBinsEditFieldLabel.Text = 'Num. Bins';
            app.NumBinsEditField = uieditfield(app.HistogramPanel, 'numeric');
            app.NumBinsEditField.Limits = [1 Inf];
            app.NumBinsEditField.Value = 30;
            app.BandwidthEditFieldLabel = uilabel(app.HistogramPanel);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.HistogramPanel, 'numeric');
            app.BandwidthEditField.Limits = [0.01 Inf];
            app.BandwidthEditField.Value = 0.15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellDensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end